classdef ReceiverPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DECODEButton                  matlab.ui.control.Button
        DecodedSignalTextArea         matlab.ui.control.TextArea
        DecodedSignalTextAreaLabel    matlab.ui.control.Label
        DecodingAlgorithmSwitch       matlab.ui.control.Switch
        DecodingAlgorithmSwitchLabel  matlab.ui.control.Label
        SamplingRateEditField         matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel    matlab.ui.control.Label
        WindowTypeDropDown            matlab.ui.control.DropDown
        WindowTypeDropDownLabel       matlab.ui.control.Label
        WindowShiftEditField          matlab.ui.control.NumericEditField
        WindowShiftEditFieldLabel     matlab.ui.control.Label
        WindowLengthEditField         matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel    matlab.ui.control.Label
        SpectogramParametersLabel     matlab.ui.control.Label
        PlotSpectogramButton          matlab.ui.control.Button
        PlotinTimeButton              matlab.ui.control.Button
        TrsEditFieldLabel             matlab.ui.control.Label
        TrsEditField                  matlab.ui.control.NumericEditField
        TdsEditField                  matlab.ui.control.NumericEditField
        TdsEditFieldLabel             matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        UIAxesSpectogram              matlab.ui.control.UIAxes
        UIAxesTime                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         % Description
        DTMF_signal % Description
       % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function RecordAudioButtonPushed(app, event)
            samplingRate=8000;
            recordingDuration=app.RecordingTimeEditField.Value;
            captureAudio(samplingRate,recordingDuration);
        end

        % Callback function
        function PlayButtonPushed(app, event)
            signal = app.DTMF_signal;  
            
            % Specify the sampling rate (replace this with your actual sampling rate)
            
            
            % Play the signal as a sound
            playAudio(signal, app.DTMF_signal.SampleRate);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            Fs = 44100;
            pause(0.5);
            recObj = audiorecorder(Fs, 16, 1); % 16 bits, 1 channel
            
            record(recObj);
            disp('started recording')
            % Store the audiorecorder object in the app data
            app.DTMF_signal = recObj;

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Check if the recorder object exists
            if isfield(app, 'DTMF_signal') && isvalid(app.DTMF_signal)
                stop(app.DTMF_signal);
                
                % Get the recorded audio data

              
                disp('Recording stopped.');
            else
                
                disp('No recording in progress.');
            end

           audioData = getaudiodata(app.DTMF_signal)


            % sampling rate when an audio is recorded is taken as human
            % signal as default, so the Fs should be 44100
           audiowrite('LastSaved.wav', audioData, app.DTMF_signal.SampleRate);
                
        end

        % Button pushed function: PlotinTimeButton
        function PlotinTimeButtonPushed(app, event)
            axes = app.UIAxesTime;

     
            dtmf_signal = getaudiodata(app.DTMF_signal);
        
           
            %signal_duration = (tone_duration+tone_pause)*length(digits) - tone_pause;
            time = (0:(length(dtmf_signal)-1)) / app.DTMF_signal.SampleRate;
            
            plot(axes,time,dtmf_signal);                
            xlabel(axes,'Time(s)','Fontsize',12);
            ylabel(axes,'Amplitude','Fontsize',12);
            title(axes,'Time Plot','Fontsize',14);
        end

        % Value changed function: DecodingAlgorithmSwitch
        function DecodingAlgorithmSwitchValueChanged(app, event)
 
            
        end

        % Button pushed function: DECODEButton
        function DECODEButtonPushed(app, event)
            switchValue = app.DecodingAlgorithmSwitch.Value;
            %dtmf_signal = getaudiodata(app.DTMF_signal);
            [audioData,samplingRate] = audioread('LastSaved.wav');
           
            %disp(Fs);
            tone_duration = app.TdsEditField.Value;
            tone_pause = app.TrsEditField.Value;
            % Decode the audio signal based on the switch value
            if switchValue == "Spectrogram"
                decodedSignal = dtmf_decoder_spectogram_GUI(audioData,samplingRate,tone_duration,tone_pause); 
            else
                decodedSignal = dtmf_goertzel_decoder_GUI(audioData,samplingRate,tone_duration,tone_pause); 
            end
            %app.DTMF_signal=decodedSignal;
            % Display the decoded signal in the text area
            app.DecodedSignalTextArea.Value = char(decodedSignal);
    
         
        end

        % Button pushed function: PlotSpectogramButton
        function PlotSpectogramButtonPushed(app, event)
            
            %audioData = getaudiodata(app.DTMF_signal);
            [audioData,samplingRate]=audioread('LastSaved.wav');
            
            %samplingRate = app.SamplingRateEditField;
            
            selectedWindow = app.WindowTypeDropDown.Value;
    
            switch selectedWindow
                    case 'Rectangular'
                        WindowType = 'rectwin'; 
                    case 'Tukey'
                        WindowType = 'tukeywin'; 
                    case 'Hamming'
                        WindowType = 'hamming'; 
                    otherwise
                        WindowType = 'rectwin'; 
            end
    
            spectogram_plotter_DTMF_GUI(app.UIAxesSpectogram,audioData,app.WindowLengthEditField.Value,app.WindowShiftEditField.Value,WindowType,samplingRate) % Takes ~10 seconds for a 10 second signal
        end

        % Value changed function: WindowLengthEditField
        function WindowLengthEditFieldValueChanged(app, event)
            
        end

        % Value changed function: WindowShiftEditField
        function WindowShiftEditFieldValueChanged(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesTime = uiaxes(app.UIFigure);
            title(app.UIAxesTime, 'Title')
            xlabel(app.UIAxesTime, 'X')
            ylabel(app.UIAxesTime, 'Y')
            zlabel(app.UIAxesTime, 'Z')
            app.UIAxesSpectogram = uiaxes(app.UIFigure);
            title(app.UIAxesSpectogram, 'Title')
            xlabel(app.UIAxesSpectogram, 'X')
            ylabel(app.UIAxesSpectogram, 'Y')
            zlabel(app.UIAxesSpectogram, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TdsEditFieldLabel = uilabel(app.UIFigure);
            app.TdsEditFieldLabel.Text = 'Td (s)';
            app.TdsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrsEditFieldLabel = uilabel(app.UIFigure);
            app.TrsEditFieldLabel.Text = 'Tr (s)';
            app.PlotinTimeButton = uibutton(app.UIFigure, 'push');
            app.PlotinTimeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotinTimeButtonPushed, true);
            app.PlotinTimeButton.Text = 'Plot in Time';
            app.PlotSpectogramButton = uibutton(app.UIFigure, 'push');
            app.PlotSpectogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSpectogramButtonPushed, true);
            app.PlotSpectogramButton.Text = 'Plot Spectogram';
            app.SpectogramParametersLabel = uilabel(app.UIFigure);
            app.SpectogramParametersLabel.Text = 'Spectogram Parameters';
            app.WindowLengthEditFieldLabel = uilabel(app.UIFigure);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowLengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditFieldValueChanged, true);
            app.WindowLengthEditField.Value = 512;
            app.WindowShiftEditFieldLabel = uilabel(app.UIFigure);
            app.WindowShiftEditFieldLabel.Text = 'Window Shift';
            app.WindowShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowShiftEditField.ValueChangedFcn = createCallbackFcn(app, @WindowShiftEditFieldValueChanged, true);
            app.WindowShiftEditField.Value = 256;
            app.WindowTypeDropDownLabel = uilabel(app.UIFigure);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.UIFigure);
            app.WindowTypeDropDown.Items = {'Rectangular', 'Tukey', 'Hamming'};
            app.WindowTypeDropDown.Value = 'Rectangular';
            app.SamplingRateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateEditField.Value = 8000;
            app.DecodingAlgorithmSwitchLabel = uilabel(app.UIFigure);
            app.DecodingAlgorithmSwitchLabel.Text = 'Decoding Algorithm';
            app.DecodingAlgorithmSwitch = uiswitch(app.UIFigure, 'slider');
            app.DecodingAlgorithmSwitch.Items = {'Spectrogram', 'Goertzel'};
            app.DecodingAlgorithmSwitch.ValueChangedFcn = createCallbackFcn(app, @DecodingAlgorithmSwitchValueChanged, true);
            app.DecodingAlgorithmSwitch.Value = 'Spectrogram';
            app.DecodedSignalTextAreaLabel = uilabel(app.UIFigure);
            app.DecodedSignalTextAreaLabel.Text = 'Decoded Signal';
            app.DecodedSignalTextArea = uitextarea(app.UIFigure);
            app.DECODEButton = uibutton(app.UIFigure, 'push');
            app.DECODEButton.ButtonPushedFcn = createCallbackFcn(app, @DECODEButtonPushed, true);
            app.DECODEButton.Text = 'DECODE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReceiverPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end