classdef TransmitterPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotinTimeButton            matlab.ui.control.Button
        AEditField                  matlab.ui.control.NumericEditField
        AEditFieldLabel             matlab.ui.control.Label
        TrsEditField                matlab.ui.control.NumericEditField
        TrsEditFieldLabel           matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        ENCODEButton                matlab.ui.control.Button
        PLAYButton                  matlab.ui.control.Button
        SAVEButton                  matlab.ui.control.Button
        SpectogramParametersLabel   matlab.ui.control.Label
        TdsEditField                matlab.ui.control.NumericEditField
        TdsEditFieldLabel           matlab.ui.control.Label
        RESETButton                 matlab.ui.control.Button
        KeyPadPanel                 matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        DButton                     matlab.ui.control.Button
        Button_13                   matlab.ui.control.Button
        Button_12                   matlab.ui.control.Button
        Button_11                   matlab.ui.control.Button
        CButton                     matlab.ui.control.Button
        Button_10                   matlab.ui.control.Button
        Button_9                    matlab.ui.control.Button
        Button_8                    matlab.ui.control.Button
        BButton                     matlab.ui.control.Button
        Button_7                    matlab.ui.control.Button
        Button_6                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        AButton                     matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        SamplingRateEditField       matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel  matlab.ui.control.Label
        WindowTypeDropDown          matlab.ui.control.DropDown
        WindowTypeDropDownLabel     matlab.ui.control.Label
        WindowShiftEditField        matlab.ui.control.NumericEditField
        WindowShiftEditFieldLabel   matlab.ui.control.Label
        WindowLengthEditField       matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel  matlab.ui.control.Label
        PlotSpectogramButton        matlab.ui.control.Button
        UIAxesTime                  matlab.ui.control.UIAxes
        UIAxesSpectogram            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Lazım olursa variablelları gir buraya
        DTMF_signal

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            key_value = '1';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            key_value = '2';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            key_value = '3';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            key_value = 'A';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            key_value = '4';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            key_value = '5';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            key_value = '6';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            key_value = 'B';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            key_value = '7';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            key_value = '8';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            key_value = '9';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            key_value = 'C';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            key_value = '*';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            key_value = '0';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            key_value = '#';
            app.EditField.Value = [app.EditField.Value, key_value];
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            key_value = 'D';
            app.EditField.Value = [app.EditField.Value, key_value];
%             app.enetered_digits = fliplr(app.EditField.Value);
%             disp(app.enetered_digits)
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.EditField.Value = '';
        end

        % Button pushed function: ENCODEButton
        function ENCODEButtonPushed(app, event)
            Fs = 8000; % Sampling frequency
            tone_duration = app.TdsEditField.Value; % Duration of each tone
            tone_pause = app.TrsEditField.Value; % Pause between tones
            digits = app.EditField.Value; % Digits to encode
            
            % Encode the digits
            encoded_signal = encoder(digits, Fs, tone_duration, tone_pause);
            app.DTMF_signal = encoded_signal;            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            
            % The encoded signal:
            signal = app.DTMF_signal;
            
            % Specify the file name and path
            filename = 'last_saved_signal.wav';
            
            % Specify the sampling rate (replace this with your actual sampling rate)
            Fs = 8000;
            
            % Save the signal to a .wav file
            audiowrite(filename, signal, Fs);
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            % The encoded signal
            signal = app.DTMF_signal;  
            
            % Specify the sampling rate (replace this with your actual sampling rate)
            Fs = 8000;
            
            % Play the signal as a sound
            sound(signal, Fs);

        end

        % Button pushed function: PlotSpectogramButton
        function PlotSpectogramButtonPushed(app, event)
            
            audioData = app.DTMF_signal;
            length(audioData)
            samplingRate = app.SamplingRateEditField.Value
            
            selectedWindow = app.WindowTypeDropDown.Value;
    
            switch selectedWindow
                    case 'Rectangular'
                        WindowType = 'rectwin'; 
                    case 'Tukey'
                        WindowType = 'tukeywin'; 
                    case 'Hamming'
                        WindowType = 'hamming'; 
                    otherwise
                        WindowType = 'rectwin'; 
                end
    
            spectogram_plotter_DTMF_GUI(app.UIAxesSpectogram,audioData,app.WindowLengthEditField.Value,app.WindowShiftEditField.Value,WindowType,samplingRate) % Takes ~10 seconds for a 10 second signal

        end

        % Button pushed function: PlotinTimeButton
        function PlotinTimeButtonPushed(app, event)
            axes = app.UIAxesTime;

            tone_duration = app.TdsEditField.Value;
            tone_pause = app.TrsEditField.Value;
            digits = app.EditField.Value;
            amplitude = app.AEditField.Value/2;
            dtmf_signal = app.DTMF_signal*amplitude;
           
            signal_duration = (tone_duration+tone_pause)*length(digits) - tone_pause;
            t_values = linspace(0,signal_duration,length(dtmf_signal));
            
            plot(axes,t_values,dtmf_signal);                
            xlabel(axes,'Time(s)','Fontsize',12);
            ylabel(axes,'Amplitude','Fontsize',12);
            title(axes,'Time Plot','Fontsize',14);
%             ylim(axes,[0 2000]);
%             xlim(axes,[0 signal_duration]);
%             yticks(axes,linspace(0, sampling_frequency, 11)); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesSpectogram = uiaxes(app.UIFigure);
            title(app.UIAxesSpectogram, 'Title')
            xlabel(app.UIAxesSpectogram, 'X')
            ylabel(app.UIAxesSpectogram, 'Y')
            zlabel(app.UIAxesSpectogram, 'Z')
            app.UIAxesTime = uiaxes(app.UIFigure);
            title(app.UIAxesTime, 'Title')
            xlabel(app.UIAxesTime, 'X')
            ylabel(app.UIAxesTime, 'Y')
            zlabel(app.UIAxesTime, 'Z')
            app.PlotSpectogramButton = uibutton(app.UIFigure, 'push');
            app.PlotSpectogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSpectogramButtonPushed, true);
            app.PlotSpectogramButton.Text = 'Plot Spectogram';
            app.WindowLengthEditFieldLabel = uilabel(app.UIFigure);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowLengthEditField.Value = 512;
            app.WindowShiftEditFieldLabel = uilabel(app.UIFigure);
            app.WindowShiftEditFieldLabel.Text = 'Window Shift';
            app.WindowShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowShiftEditField.Value = 256;
            app.WindowTypeDropDownLabel = uilabel(app.UIFigure);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.UIFigure);
            app.WindowTypeDropDown.Items = {'Rectangular', 'Tukey', 'Hamming'};
            app.WindowTypeDropDown.Value = 'Rectangular';
            app.SamplingRateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateEditField.Value = 8000;
            app.KeyPadPanel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.KeyPadPanel);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.AButton = uibutton(app.GridLayout, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '4';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '5';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '6';
            app.BButton = uibutton(app.GridLayout, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '7';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '8';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '9';
            app.CButton = uibutton(app.GridLayout, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '*';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '0';
            app.Button_13 = uibutton(app.GridLayout, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '#';
            app.DButton = uibutton(app.GridLayout, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.TdsEditFieldLabel = uilabel(app.UIFigure);
            app.TdsEditFieldLabel.Text = 'Td (s)';
            app.TdsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpectogramParametersLabel = uilabel(app.UIFigure);
            app.SpectogramParametersLabel.Text = 'Spectogram Parameters';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.ENCODEButton = uibutton(app.UIFigure, 'push');
            app.ENCODEButton.ButtonPushedFcn = createCallbackFcn(app, @ENCODEButtonPushed, true);
            app.ENCODEButton.Text = 'ENCODE';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.TrsEditFieldLabel = uilabel(app.UIFigure);
            app.TrsEditFieldLabel.Text = 'Tr (s)';
            app.TrsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrsEditField.Value = 0.5;
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditField.Value = 0.5;
            app.PlotinTimeButton = uibutton(app.UIFigure, 'push');
            app.PlotinTimeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotinTimeButtonPushed, true);
            app.PlotinTimeButton.Text = 'Plot in Time';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransmitterPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end